classdef FordFulkersonAppEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlgoritmodeFordFulkersonUIFigure  matlab.ui.Figure
        CargarmatrizdecapacidadesButton  matlab.ui.control.Button
        CalcularflujomximoButton   matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        NodoorigenEditFieldLabel   matlab.ui.control.Label
        NodoorigenEditField        matlab.ui.control.NumericEditField
        NododestinoEditFieldLabel  matlab.ui.control.Label
        NododestinoEditField       matlab.ui.control.NumericEditField
        FlujomximoEditFieldLabel   matlab.ui.control.Label
        FlujomximoEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        filename % nombre de archivo de la matriz de adyacencias
        A        % matriz de adyacencias importada
        G        % grafo original
        S        % Nodo origen
        T        % Nodo destino
        Res      % Matriz residual
        Flux     % Flujo
    end
    
    methods (Access = private)
        

function [f, cap] = FordFulkerson(app, CapMatrix, S, T)

cap = CapMatrix;
f = 0;

while true
    
    % Hallar 
    path = findPath(app, cap, S, T);
    
    if path(1) == 0
        break
    end
    
    % Flujo 
    flow = max(max(cap));
    
    for j = 2:length(path)
        flow = min(flow, cap(path(j), path(j-1)));
    end
    
    for j = 2:length(path)
        a = path(j); b = path(j-1);
        cap(a,b) = cap(a,b) - flow;
        cap(b,a) = cap(b,a) + flow;
    end
    
    f = f + flow;
end

end


function F = findPath(~, CapMatrix, S, T)
% BFS (Breadth-first Search)

% Número de nodos
n = length(CapMatrix);

% Contadores de frente y trasero
front = 1; 
back = 2;

% Fila (queue)
queue = zeros(1,n);
queue(front) = S;

% Iniciar conjunto de predecesores
pred = zeros(1,n);
pred(S) = S; 

while front ~= back
    % Elegimos nuevo nodo y aumentamos 1 a frente
    node = queue(front);
	front = front + 1;
    
    % Iterar sobre todos los nodos
    for i = 1:n
        % Si el nodo no es predecesor y la capacidad máxima es positiva,
        % agregarlo a la cola y aumentar 1 al contador de trasero 'back'.
        % Además, actualizar el predecesor
        if pred(i) == 0 && CapMatrix(node,i) > 0
            queue(back) = i;
            back = back + 1;
            pred(i) = node;
        end
    end
end

% Iniciar variable de camino
path = zeros(1,n);


if pred(T) ~= 0
    i = T; c = 1;
	
    while pred(i) ~= i
    	path(c) = i;
        c = c + 1;
        i = pred(i);
    end
    
    path(c) = S;
    path(c+1:n) = [];
end

% Devolver camino final
F = path;
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarmatrizdecapacidadesButton
        function CargarmatrizdecapacidadesButtonPushed(app, event)
            
            % Abrir cuadro de diálogo para elegir matriz
            [file, path] = uigetfile("*.csv");
            app.filename = strcat(path, file);
            
            % Cargar archivo en matriz A
            app.A = readmatrix(app.filename);
            
            % Crear grafo
            app.G = digraph(app.A,"omitselfloops");
            
            % Visualizar grafo con pesos
            plot(app.UIAxes, app.G, 'EdgeLabel', app.G.Edges.Weight);
            
        end

        % Button pushed function: CalcularflujomximoButton
        function CalcularflujomximoButtonPushed(app, event)
            % Calcular y mostrar flujo máximo entre origen y destino
            [app.Flux, app.Res] = app.FordFulkerson(app.A, app.S, app.T);
            
            % Mostrar flujo
            app.FlujomximoEditField.Value = app.Flux;
            
            % Crear grafo residual
            GRes = digraph(app.Res,"omitselfloops");
            
            % Visualizar grafo residual
            plot(app.UIAxes, GRes, 'EdgeLabel', GRes.Edges.Weight);
            
        end

        % Value changed function: NodoorigenEditField
        function NodoorigenEditFieldValueChanged(app, event)
            value = app.NodoorigenEditField.Value;
            
            if ischar(value)
                value = str2double(value);
            end
            
            app.S = value;
        end

        % Value changed function: NododestinoEditField
        function NododestinoEditFieldValueChanged(app, event)
            value = app.NododestinoEditField.Value;
            
            if ischar(value)
                value = str2double(value);
            end
            
            app.T = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlgoritmodeFordFulkersonUIFigure = uifigure('Visible', 'off');
            app.AlgoritmodeFordFulkersonUIFigure.Name = 'Algoritmo de Ford-Fulkerson';
            app.CargarmatrizdecapacidadesButton = uibutton(app.AlgoritmodeFordFulkersonUIFigure, 'push');
            app.CargarmatrizdecapacidadesButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmatrizdecapacidadesButtonPushed, true);
            app.CargarmatrizdecapacidadesButton.Text = {'Cargar matriz '; 'de capacidades'};
            app.CalcularflujomximoButton = uibutton(app.AlgoritmodeFordFulkersonUIFigure, 'push');
            app.CalcularflujomximoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularflujomximoButtonPushed, true);
            app.CalcularflujomximoButton.Text = 'Calcular flujo máximo';
            app.UIAxes = uiaxes(app.AlgoritmodeFordFulkersonUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.NodoorigenEditFieldLabel = uilabel(app.AlgoritmodeFordFulkersonUIFigure);
            app.NodoorigenEditFieldLabel.Text = 'Nodo origen';
            app.NodoorigenEditField = uieditfield(app.AlgoritmodeFordFulkersonUIFigure, 'numeric');
            app.NodoorigenEditField.ValueChangedFcn = createCallbackFcn(app, @NodoorigenEditFieldValueChanged, true);
            app.NododestinoEditFieldLabel = uilabel(app.AlgoritmodeFordFulkersonUIFigure);
            app.NododestinoEditFieldLabel.Text = 'Nodo destino';
            app.NododestinoEditField = uieditfield(app.AlgoritmodeFordFulkersonUIFigure, 'numeric');
            app.NododestinoEditField.ValueChangedFcn = createCallbackFcn(app, @NododestinoEditFieldValueChanged, true);
            app.FlujomximoEditFieldLabel = uilabel(app.AlgoritmodeFordFulkersonUIFigure);
            app.FlujomximoEditFieldLabel.Text = 'Flujo máximo';
            app.FlujomximoEditField = uieditfield(app.AlgoritmodeFordFulkersonUIFigure, 'numeric');
            % Show the figure after all components are created
            app.AlgoritmodeFordFulkersonUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FordFulkersonAppEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlgoritmodeFordFulkersonUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlgoritmodeFordFulkersonUIFigure)
        end
    end
end